classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button       matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
        Label        matlab.ui.control.Label
        EditField    matlab.ui.control.EditField
        Button_3     matlab.ui.control.Button
        Button_4     matlab.ui.control.Button
        Button_5     matlab.ui.control.Button
        Button_6     matlab.ui.control.Button
        Button_7     matlab.ui.control.Button
        Button_8     matlab.ui.control.Button
        Button_9     matlab.ui.control.Button
        Button_10    matlab.ui.control.Button
        Button_11    matlab.ui.control.Button
        Button_12    matlab.ui.control.Button
        Label_2      matlab.ui.control.Label
        EditField_2  matlab.ui.control.NumericEditField
        Label_3      matlab.ui.control.Label
        EditField_3  matlab.ui.control.NumericEditField
    end

    
   properties (Access = private)
        imageFiles           % 用于存储所有图像路径
        currentImageIndex    % 当前显示的图像索引
        processedImages      % 临时存储处理后的图像结果
        processedTitles      % 临时存储处理图像对应的标题
    end
    
    methods (Access = private)
        % 自定义直方图均衡化函数
        function equalizedImage = customHistogramEqualization(~, img, numLevels)
            % 确保图像为灰度图像
            if size(img, 3) == 3
                img = rgb2gray(img);
            end
    
            % 获取图像的宽度和高度
            [height, width] = size(img);
    
            % 统计图像直方图
            histogramValues = imhist(img, numLevels);
    
            % 计算累计频率
            cumulativeFrequency = zeros(numLevels, 1);
            cumulativeFrequency(1) = histogramValues(1);
            for i = 2:numLevels
                cumulativeFrequency(i) = cumulativeFrequency(i - 1) + histogramValues(i);
            end
    
            % 计算修正的灰度级
            adjustedGrayLevels = zeros(numLevels, 1);
            for i = 1:numLevels
                adjustedGrayLevels(i) = floor(cumulativeFrequency(i) * (numLevels - 1) / (width * height) + 0.5);
            end
    
            % 生成均衡化后的图像
            equalizedImage = zeros(height, width);
            for x = 1:width
                for y = 1:height
                    levelIndex = floor(double(img(y, x)) * (numLevels - 1) / 255) + 1;
                    equalizedImage(y, x) = adjustedGrayLevels(levelIndex) * (255 / (numLevels - 1));
                end
            end
    
            % 将均衡化后的图像转换回 uint8 类型
            equalizedImage = uint8(equalizedImage);
        end
    
        % 线性变换实现灰度图对比度增强
        function enhancedImage = linearContrastEnhancement(~, img)
            % 确保图像为灰度图像
            if size(img, 3) == 3
                img = rgb2gray(img);
            end
    
            % 设置默认参数
            alpha = 1.5; % 对比度增强系数
            beta = 20;   % 亮度偏移值
    
            % 转换图像为双精度以便计算
            img = double(img);
    
            % 应用线性变换
            enhancedImage = alpha * img + beta;
    
            % 裁剪像素值到 [0, 255]
            enhancedImage = min(max(enhancedImage, 0), 255);
    
            % 转回 uint8 类型
            enhancedImage = uint8(enhancedImage);
        end
    
        % 对数变换实现灰度图对比度增强
        function enhancedImage = logContrastEnhancement(~, img)
            % 确保图像为灰度图像
            if size(img, 3) == 3
                img = rgb2gray(img);
            end
    
            % 设置默认参数
            c = 30; % 对比度调整系数
    
            % 转换图像为双精度
            img = double(img);
    
            % 应用对数变换
            enhancedImage = c * log(1 + img);
    
            % 归一化到 [0, 255]
            enhancedImage = 255 * (enhancedImage / max(enhancedImage(:)));
    
            % 转回 uint8 类型
            enhancedImage = uint8(enhancedImage);
        end
    
        % 指数变换实现灰度图对比度增强
        function enhancedImage = expContrastEnhancement(~, img)
            % 确保图像为灰度图像
            if size(img, 3) == 3
                img = rgb2gray(img);
            end
    
            % 设置默认参数
            c = 1;     % 对比度调整常数
            gamma = 0.5; % 指数调整值
    
            % 转换图像为双精度并归一化
            img = double(img) / 255;
    
            % 应用指数变换
            enhancedImage = c * (img .^ gamma);
    
            % 恢复到 [0, 255]
            enhancedImage = 255 * enhancedImage;
    
            % 转回 uint8 类型
            enhancedImage = uint8(enhancedImage);
        end
    
         % 图像处理函数
        function equalizedImage = processImage(app, imagePath)
            % 读取图像
            img = imread(imagePath);

            % 调用自定义直方图均衡化函数
            numLevels = 256; % 设置灰度级数
            equalizedImage = app.customHistogramEqualization(img, numLevels);
        end

        function scaledImage = resizeImage(~,img, kx, ky)
            % 图像缩放函数，基于双线性插值
            % 参数:
            %   img: 输入图像（可以是灰度或RGB）
            %   kx: 宽度缩放倍数
            %   ky: 高度缩放倍数
            % 返回:
            %   scaledImage: 缩放后的图像
        
            % 获取图像的大小
            [height, width, channels] = size(img);
        
            % 计算放大后图像的大小
            new_height = round(height * ky);
            new_width = round(width * kx);
        
            % 初始化放大后的新图像
            scaledImage = zeros(new_height, new_width, channels, 'like', img);
        
            % 对每个通道分别进行双线性插值
            for c = 1:channels
                for i = 1:new_height
                    for j = 1:new_width
                        % 计算映射回原图像的浮点位置
                        x = (i - 1) / (new_height - 1) * (height - 1) + 1;
                        y = (j - 1) / (new_width - 1) * (width - 1) + 1;
        
                        % 找到邻近的4个像素位置
                        x1 = floor(x); x2 = min(x1 + 1, height);
                        y1 = floor(y); y2 = min(y1 + 1, width);
        
                        % 计算权重
                        dx = x - x1;
                        dy = y - y1;
        
                        % 双线性插值计算
                        scaledImage(i, j, c) = (1 - dx) * (1 - dy) * img(x1, y1, c) + ...
                                               dx * (1 - dy) * img(x2, y1, c) + ...
                                               (1 - dx) * dy * img(x1, y2, c) + ...
                                               dx * dy * img(x2, y2, c);
                    end
                end
            end
        end
    
        function rotatedImage = rotateImageCustom(~, img, angle)
            % 图像旋转函数，支持 RGB 图像旋转并保留色彩信息
            % 参数:
            %   img: 输入的图像矩阵（灰度或 RGB）
            %   angle: 旋转角度（单位：度，正数为顺时针，负数为逆时针）
            % 返回:
            %   rotatedImage: 旋转后的图像
        
            % 判断输入图像是否为灰度图或 RGB 图像
            [rows, cols, channels] = size(img);
        
            % 将角度转换为弧度制
            rad = angle * pi / 180;
        
            % 计算旋转后的新图像分辨率
            new_rows = round(abs(cos(rad) * rows) + abs(sin(rad) * cols));
            new_cols = round(abs(cos(rad) * cols) + abs(sin(rad) * rows));
        
            % 初始化旋转后的图像矩阵
            rotatedImage = zeros(new_rows, new_cols, channels, 'like', img);
        
            % 计算中心点偏移量
            x_center = (cols + 1) / 2;
            y_center = (rows + 1) / 2;
            new_x_center = (new_cols + 1) / 2;
            new_y_center = (new_rows + 1) / 2;
        
            % 遍历每个通道并进行双线性插值
            for c = 1:channels
                for i = 1:new_rows
                    for j = 1:new_cols
                        % 将新图像坐标映射回原图像
                        x_shifted = j - new_x_center;
                        y_shifted = i - new_y_center;
        
                        % 应用逆旋转变换
                        x_original = x_shifted * cos(-rad) - y_shifted * sin(-rad) + x_center;
                        y_original = x_shifted * sin(-rad) + y_shifted * cos(-rad) + y_center;
        
                        % 检查映射位置是否在原图像范围内
                        if x_original >= 1 && x_original <= cols && y_original >= 1 && y_original <= rows
                            % 计算四个参考点坐标
                            x1 = floor(x_original);
                            x2 = min(x1 + 1, cols);
                            y1 = floor(y_original);
                            y2 = min(y1 + 1, rows);
        
                            % 获取四个参考点的像素值
                            F11 = double(img(y1, x1, c));
                            F12 = double(img(y1, x2, c));
                            F21 = double(img(y2, x1, c));
                            F22 = double(img(y2, x2, c));
        
                            % 计算插值权重
                            dx = x_original - x1;
                            dy = y_original - y1;
        
                            % 进行双线性插值
                            rotatedImage(i, j, c) = (1 - dx) * (1 - dy) * F11 + ...
                                                    dx * (1 - dy) * F12 + ...
                                                    (1 - dx) * dy * F21 + ...
                                                    dx * dy * F22;
                        end
                    end
                end
            end
        
            % 将结果转换为 uint8 类型
            rotatedImage = uint8(rotatedImage);
        end

        function noisyImage = addNoise(~, img, noiseType)
            % 图像加噪函数
            % 参数:
            %   img: 输入图像（可以是灰度或 RGB）
            %   noiseType: 噪声类型编号
            %              1: 高斯噪声
            %              2: 盐椒噪声
            %              3: 均匀噪声
            %              4: 泊松噪声
            %              5: 乘性噪声
            % 返回:
            %   noisyImage: 添加噪声后的图像
        
            % 检查图像类型并转换为 double 类型以支持加噪计算
            if ~isa(img, 'double')
                img = im2double(img); % 将输入图像转换为 [0, 1] 的范围
            end
        
            % 根据噪声类型编号添加噪声
            switch noiseType
                case 1  % 高斯噪声
                    mean = 0;         % 噪声均值
                    variance = 0.01;  % 噪声方差
                    noisyImage = img + sqrt(variance) * randn(size(img)) + mean;
        
                case 2  % 盐椒噪声
                    density = 0.05;  % 噪声密度（添加噪声的像素比例）
                    noisyImage = imnoise(img, 'salt & pepper', density);
        
                case 3  % 均匀噪声
                    scale = 0.1; % 噪声强度
                    noisyImage = img + scale * (rand(size(img)) - 0.5);
        
                case 4  % 泊松噪声
                    noisyImage = imnoise(img, 'poisson');
        
                case 5  % 乘性噪声（乘性高斯噪声）
                    variance = 0.05; % 噪声方差
                    noisyImage = img .* (1 + sqrt(variance) * randn(size(img)));
        
                otherwise
                    error('无效的噪声类型编号。有效编号为: 1 (高斯), 2 (盐椒), 3 (均匀), 4 (泊松), 5 (乘性)');
            end
        
            % 确保图像值仍然在 [0, 1] 范围内
            noisyImage = min(max(noisyImage, 0), 1);
        
            % 如果原始图像是 uint8 类型，则将加噪后的图像转换回 uint8
            if isa(img, 'uint8')
                noisyImage = im2uint8(noisyImage);
            end
        end

        function filteredImage = spatialFilter(~, processedImages, index, filterSize)
            % 空域滤波函数
            % 参数:
            %   processedImages: 包含处理后的图像的 cell 数组
            %   index: 要处理的图像索引
            %   filterType: 滤波器类型 ('mean' 或 'median')
            %   filterSize: 滤波器窗口大小（如 3 表示 3x3 滤波器）
            % 返回:
            %   filteredImage: 滤波后的图像
        
            % 获取当前要处理的噪声图像
            noisyImage = processedImages{index};
        
            % 转换为灰度图（如果是 RGB 图像）
            if size(noisyImage, 3) == 3
                noisyImage = rgb2gray(noisyImage);
            end
            h = fspecial('average', filterSize); % 创建均值滤波器
            filteredImage = imfilter(noisyImage, h, 'replicate'); % 进行滤波
        end

        function filteredImage = frequencyFilter(~, processedImages, index, cutoffFrequency)
            % 频域滤波函数
            % 参数:
            %   processedImages: 包含处理后的图像的 cell 数组
            %   index: 要处理的图像索引
            %   cutoffFrequency: 截止频率，范围 [0, 1]
            % 返回:
            %   filteredImage: 滤波后的图像
        
            % 获取当前要处理的噪声图像
            noisyImage = processedImages{index};
        
            % 转换为灰度图（如果是 RGB 图像）
            if size(noisyImage, 3) == 3
                noisyImage = rgb2gray(noisyImage);
            end
        
            % 获取图像大小
            [M, N] = size(noisyImage);
        
            % 创建频域网格
            [U, V] = meshgrid(-N/2:N/2-1, -M/2:M/2-1);
            D = sqrt(U.^2 + V.^2) / max(M, N); % 归一化频率
            % 低通滤波器
            H = double(D <= cutoffFrequency);
            % 进行傅里叶变换
            F = fft2(double(noisyImage));
            F = fftshift(F); % 移动零频分量到中心
            % 应用滤波器
            G = H .* F;
            % 逆傅里叶变换
            G = ifftshift(G);
            filteredImage = real(ifft2(G));
            % 归一化到 [0, 1]
            filteredImage = mat2gray(filteredImage);
        end
    
        function edgeImage = edgeDetection(~, image, method)
            % 图像边缘检测函数
            % 参数:
            %   image: 输入图像（可以是灰度图或 RGB 图）
            %   method: 使用的边缘检测方法
            %           1: Robert 算子
            %           2: Prewitt 算子
            %           3: Sobel 算子
            %           4: 拉普拉斯算子
            % 返回:
            %   edgeImage: 边缘检测后的图像
            % 将 RGB 图像转换为灰度图
            if size(image, 3) == 3
                image = rgb2gray(image);
            end
            % 转换为 double 类型以支持数学运算
            image = im2double(image);
            % 根据选择的算子进行边缘检测
            switch lower(method)
                case 1  % Robert 算子
                    % 定义 Robert 算子的滤波器
                    robertX = [1 0; 0 -1];
                    robertY = [0 1; -1 0];
                    % 应用滤波器
                    gradX = imfilter(image, robertX, 'replicate');
                    gradY = imfilter(image, robertY, 'replicate');
                    % 计算梯度幅值
                    edgeImage = sqrt(gradX.^2 + gradY.^2);
                case 2  % Prewitt 算子
                    % 定义 Prewitt 算子的滤波器
                    prewittX = [-1 0 1; -1 0 1; -1 0 1];
                    prewittY = [-1 -1 -1; 0 0 0; 1 1 1];
                    % 应用滤波器
                    gradX = imfilter(image, prewittX, 'replicate');
                    gradY = imfilter(image, prewittY, 'replicate');
                    % 计算梯度幅值
                    edgeImage = sqrt(gradX.^2 + gradY.^2);
                case 3  % Sobel 算子
                    % 定义 Sobel 算子的滤波器
                    sobelX = [-1 0 1; -2 0 2; -1 0 1];
                    sobelY = [-1 -2 -1; 0 0 0; 1 2 1];
                    % 应用滤波器
                    gradX = imfilter(image, sobelX, 'replicate');
                    gradY = imfilter(image, sobelY, 'replicate');
                    % 计算梯度幅值
                    edgeImage = sqrt(gradX.^2 + gradY.^2);
                case 4  % 拉普拉斯算子
                    % 定义拉普拉斯算子的滤波器
                    laplacianFilter = [0 1 0; 1 -4 1; 0 1 0];
                    % 应用滤波器
                    edgeImage = imfilter(image, laplacianFilter, 'replicate');
                otherwise
                    error('无效的方法，请选择 "robert", "prewitt", "sobel" 或 "laplacian"');
            end
            % 归一化结果到 [0, 1]
            edgeImage = mat2gray(edgeImage);
        end

        function extractedTarget = extractTargetByColorAndThreshold(~, img, threshold)
            % 从图像中提取目标的函数
            % 参数:
            %   img: 输入的图像（RGB 或灰度）
            %   threshold: 阈值，用于二值化分割目标
            % 返回:
            %   extractedTarget: 提取的目标图像（RGB 图像中保留目标，其他区域设为黑色）
        
            % 如果是 RGB 图像，转换为灰度
            if size(img, 3) == 3
                grayImg = rgb2gray(img);
            else
                grayImg = img;
            end
        
            % 将灰度图归一化到 [0, 1] 范围
            grayImg = im2double(grayImg);
        
            % 二值化分割
            binaryMask = grayImg > threshold;
        
            % 去掉小区域噪声
            binaryMask = bwareaopen(binaryMask, 50);
        
            % 填充孔洞
            binaryMask = imfill(binaryMask, 'holes');
        
            % 使用二值掩码提取目标区域
            extractedTarget = bsxfun(@times, img, cast(binaryMask, 'like', img));
        end
    
        function lbpFeatures = extractLBPFeaturesBuiltin(~, img)
            % 手动实现 LBP 特征提取
            % 参数:
            %   img: 输入灰度图像
            % 返回:
            %   lbpFeatures: 提取的 LBP 特征（直方图）
        
            % 将图像转换为灰度（如果是 RGB 图像）
            if size(img, 3) == 3
                img = rgb2gray(img);
            end
            % 提取 LBP 特征
            % 'Upright' 设置为 true，表示不考虑旋转
            % 'CellSize' 设置为 16x16 的单元格大小
            lbpFeatures = extractLBPFeatures(img, 'Upright', true, 'CellSize', [16 16]);
        end


        function [hogFeatures, visualization] = extractHOGFeaturesBuiltin(~, img)
            % 使用 MATLAB 内置函数提取 HOG 特征
            % 参数:
            %   img: 输入的灰度图或 RGB 图像
            % 返回:
            %   hogFeatures: HOG 特征向量
            %   visualization: 可视化对象（用于显示 HOG 特征）
        
            % 将图像转换为灰度（如果是 RGB 图像）
            if size(img, 3) == 3
                img = rgb2gray(img);
            end
        
            % 提取 HOG 特征和可视化信息
            % 'CellSize' 设置为 8x8 的单元格大小
            [hogFeatures, visualization] = extractHOGFeatures(img, 'CellSize', [8 8]);
        end

    
        % 显示当前处理的图像
        function displayImage(app)
            if isempty(app.processedImages)
                return;
            end

            % 获取当前要显示的数据
            imageToDisplay = app.processedImages{app.currentImageIndex};
            titleToDisplay = app.processedTitles{app.currentImageIndex};

            % 显示图像
            imshow(imageToDisplay, 'Parent', app.UIAxes);
            app.UIAxes.Title.String = titleToDisplay;
        end
    end


    methods (Access = private)

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
             % 从 EditField 获取图像路径
            imagePath = app.EditField.Value;

            % 检查路径是否有效
            if ~isfile(imagePath)
                uialert(app.UIFigure, '路径无效，请输入正确的图片路径！', '错误');
                return;
            end

            % 检查文件扩展名是否有效
            [~, ~, ext] = fileparts(imagePath);
            validExtensions = {'.png', '.jpg', '.jpeg', '.bmp', '.tiff'};
            if ~ismember(lower(ext), validExtensions)
                uialert(app.UIFigure, '文件格式不支持，请输入有效的图片路径！', '错误');
                return;
            end

            % 初始化处理结果
            app.imageFiles = {imagePath}; % 存储图像路径
            app.currentImageIndex = 1;
            app.processedImages = {};
            app.processedTitles = {};

            % 调用图像处理函数
            equalizedImage = app.processImage(imagePath);

            % 存储均衡化图像
            app.processedImages{end + 1} = equalizedImage;
            app.processedTitles{end + 1} = ['均衡化图像 - ' imagePath];

            % 显示第一张处理结果
            app.displayImage();
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            % 从 EditField 获取图像路径
            imagePath = app.EditField.Value;
        
            % 检查路径是否有效
            if ~isfile(imagePath)
                uialert(app.UIFigure, '路径无效，请输入正确的图片路径！', '错误');
                return;
            end
        
            % 检查文件扩展名是否有效
            [~, ~, ext] = fileparts(imagePath);
            validExtensions = {'.png', '.jpg', '.jpeg', '.bmp', '.tiff'};
            if ~ismember(lower(ext), validExtensions)
                uialert(app.UIFigure, '文件格式不支持，请输入有效的图片路径！', '错误');
                return;
            end
        
            % 初始化处理结果
            app.imageFiles = {imagePath}; % 存储图像路径
            app.currentImageIndex = 1;
            app.processedImages = {};
            app.processedTitles = {};
        
            % 读取图像
            img = imread(imagePath);
            % 展示灰度图
            imggray = rgb2gray(img);
            app.processedImages{end + 1} = imggray;
            app.processedTitles{end + 1} = ['灰度图 - ' imagePath];
        
            % 调用线性对比度增强
            linearEnhanced = app.linearContrastEnhancement(img);
            app.processedImages{end + 1} = linearEnhanced;
            app.processedTitles{end + 1} = ['线性对比度增强 - ' imagePath];
        
            % 调用对数对比度增强
            logEnhanced = app.logContrastEnhancement(img);
            app.processedImages{end + 1} = logEnhanced;
            app.processedTitles{end + 1} = ['对数对比度增强 - ' imagePath];
        
            % 调用指数对比度增强
            expEnhanced = app.expContrastEnhancement(img);
            app.processedImages{end + 1} = expEnhanced;
            app.processedTitles{end + 1} = ['指数对比度增强 - ' imagePath];
        
            % 显示第一张处理结果
            app.displayImage();
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            if app.currentImageIndex < numel(app.processedImages)
                app.currentImageIndex = app.currentImageIndex + 1;
                app.displayImage();
            else
                uialert(app.UIFigure, '已经是最后一张！', '提示');
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            if app.currentImageIndex > 1
                app.currentImageIndex = app.currentImageIndex - 1;
                app.displayImage();
            else
                uialert(app.UIFigure, '已经是第一张！', '提示');
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 从 EditField 获取图像路径
            imagePath = app.EditField.Value;
        
            % 检查路径是否有效
            if ~isfile(imagePath)
                uialert(app.UIFigure, '路径无效，请输入正确的图片路径！', '错误');
                return;
            end
        
            % 检查文件扩展名是否有效
            [~, ~, ext] = fileparts(imagePath);
            validExtensions = {'.png', '.jpg', '.jpeg', '.bmp', '.tiff'};
            if ~ismember(lower(ext), validExtensions)
                uialert(app.UIFigure, '文件格式不支持，请输入有效的图片路径！', '错误');
                return;
            end
        
            % 初始化处理结果
            app.imageFiles = {imagePath}; % 存储图像路径
            app.currentImageIndex = 1;
            app.processedImages = {};
            app.processedTitles = {};
        
            % 读取图像
            img = imread(imagePath);
            % 从 EditField_2 和 EditField_3 获取缩放倍数
            kx = app.EditField_2.Value; % 宽度缩放倍数
            ky = app.EditField_3.Value; % 高度缩放倍数
        
            % 检查缩放倍数是否有效
            if isnan(kx) || kx <= 0 || isnan(ky) || ky <= 0
                % 格式化错误信息，包含 kx 和 ky 的值
                message = sprintf('缩放倍数必须是正数！当前宽度倍数: %.2f，高度倍数: %.2f', kx, ky);
                uialert(app.UIFigure, message, '错误');
                return;
            end
        
            % 调用图像缩放函数
            scaledImage = app.resizeImage(img, kx, ky);
            % 存储缩放前的图像
            app.processedImages{end + 1} = img;
            app.processedTitles{end + 1} = ['原图像'];
            % 存储缩放后的图像
            app.processedImages{end + 1} = scaledImage;
            app.processedTitles{end + 1} = ['缩放图像 - 宽度倍数: ' num2str(kx) ', 高度倍数: ' num2str(ky)];
        
            % 显示缩放后的图像
            app.displayImage();
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % 从 EditField 获取图像路径
            imagePath = app.EditField.Value;
        
            % 检查路径是否有效
            if ~isfile(imagePath)
                uialert(app.UIFigure, '路径无效，请输入正确的图片路径！', '错误');
                return;
            end
        
            % 检查文件扩展名是否有效
            [~, ~, ext] = fileparts(imagePath);
            validExtensions = {'.png', '.jpg', '.jpeg', '.bmp', '.tiff'};
            if ~ismember(lower(ext), validExtensions)
                uialert(app.UIFigure, '文件格式不支持，请输入有效的图片路径！', '错误');
                return;
            end
        
            % 初始化处理结果
            app.imageFiles = {imagePath}; % 存储图像路径
            app.currentImageIndex = 1;
            app.processedImages = {};
            app.processedTitles = {};
        
            % 读取图像
            img = imread(imagePath);
        
            % 从 EditField_2 获取旋转角度
            angle = app.EditField_2.Value; % 旋转角度
        
            % 检查旋转角度是否有效
            if isnan(angle)
                % 格式化错误信息
                message = '旋转角度无效，请输入一个有效的数字！';
                uialert(app.UIFigure, message, '错误');
                return;
            end
        
            % 调用图像旋转函数
            rotatedImage = app.rotateImageCustom(img, angle); % 使用自定义的图像旋转函数
        
            % 存储旋转前的图像
            app.processedImages{end + 1} = img;
            app.processedTitles{end + 1} = '原图像';
        
            % 存储旋转后的图像
            app.processedImages{end + 1} = rotatedImage;
            app.processedTitles{end + 1} = ['旋转图像 - 角度: ' num2str(angle) '°'];
        
            % 显示第一个处理结果
            app.displayImage();
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            % 从 EditField 获取图像路径
            imagePath = app.EditField.Value;
        
            % 检查路径是否有效
            if ~isfile(imagePath)
                uialert(app.UIFigure, '路径无效，请输入正确的图片路径！', '错误');
                return;
            end
        
            % 检查文件扩展名是否有效
            [~, ~, ext] = fileparts(imagePath);
            validExtensions = {'.png', '.jpg', '.jpeg', '.bmp', '.tiff'};
            if ~ismember(lower(ext), validExtensions)
                uialert(app.UIFigure, '文件格式不支持，请输入有效的图片路径！', '错误');
                return;
            end
        
            % 初始化处理结果
            app.imageFiles = {imagePath}; % 存储图像路径
            app.currentImageIndex = 1;
            app.processedImages = {};
            app.processedTitles = {};
        
            % 读取图像
            img = imread(imagePath);
        
            % 从 NumericEditField 获取噪声类型编号
            noiseType = round(app.EditField_2.Value); % 假设用户输入噪声类型编号
        
            % 检查噪声类型编号是否有效
            if noiseType < 1 || noiseType > 5
                uialert(app.UIFigure, '请输入有效的噪声类型编号（1-5）！', '错误');
                return;
            end
        
            % 调用加噪函数
            noisyImage = app.addNoise(img, noiseType);
        
            % 存储加噪后的图像
            app.processedImages{end + 1} = noisyImage;
            app.processedTitles{end + 1} = ['加噪图像 - 类型: ', num2str(noiseType)];
        
            % 显示加噪后的图像
            app.displayImage();
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            % 使用均值滤波
            index = 1; % 处理第 1 张图像
            filterSize = 3; % 滤波器窗口大小
            filteredImage = app.spatialFilter(app.processedImages, index, filterSize);
            % 存储滤波后的图像
            app.processedImages = {};
            app.processedTitles = {};
            app.processedImages{end + 1} = filteredImage;
            app.processedTitles{end + 1} = ['空域滤波图像'];
        
            % 显示滤波后的图像
            app.displayImage();
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            % 使用低通滤波
            index = 1; % 处理第 1 张图像
            cutoffFrequency = 0.1; % 截止频率
            filteredImage = app.frequencyFilter(app.processedImages, index, cutoffFrequency);
            % 存储滤波后的图像
            app.processedImages = {};
            app.processedTitles = {};
            app.processedImages{end + 1} = filteredImage;
            app.processedTitles{end + 1} = ['频域滤波图像'];
        
            % 显示滤波后的图像
            app.displayImage();
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            % 从 EditField 获取图像路径
            imagePath = app.EditField.Value;
        
            % 检查路径是否有效
            if ~isfile(imagePath)
                uialert(app.UIFigure, '路径无效，请输入正确的图片路径！', '错误');
                return;
            end
        
            % 检查文件扩展名是否有效
            [~, ~, ext] = fileparts(imagePath);
            validExtensions = {'.png', '.jpg', '.jpeg', '.bmp', '.tiff'};
            if ~ismember(lower(ext), validExtensions)
                uialert(app.UIFigure, '文件格式不支持，请输入有效的图片路径！', '错误');
                return;
            end
        
            % 初始化处理结果
            app.imageFiles = {imagePath}; % 存储图像路径
            app.currentImageIndex = 1;
            app.processedImages = {};
            app.processedTitles = {};
        
            % 读取图像
            img = imread(imagePath);
        
            % 从 NumericEditField 获取操作算子类型编号
            Type = round(app.EditField_2.Value); % 假设用户输入噪声类型编号
        
            % 算子类型映射表
            typeMap = {'robert', 'prewitt', 'sobel', 'laplacian'};
        
            % 验证输入类型编号是否有效
            if Type < 1 || Type > numel(typeMap)
                error('无效的算子类型编号。有效编号为 1 (Robert), 2 (Prewitt), 3 (Sobel), 4 (Laplacian)');
            end
        
            % 获取对应的算子类型名称
            type = typeMap{Type};
        
            % 调用边缘提取函数
            edgeImage = app.edgeDetection(img, Type);
        
            % 存储处理后的图像及标题
            app.processedImages{end + 1} = edgeImage;
            app.processedTitles{end + 1} = ['算子 - 类型: ', type];
        
            % 显示加噪后的图像
            app.displayImage(); 
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            % 从 EditField 获取图像路径
            imagePath = app.EditField.Value;
        
            % 检查路径是否有效
            if ~isfile(imagePath)
                uialert(app.UIFigure, '路径无效，请输入正确的图片路径！', '错误');
                return;
            end
        
            % 检查文件扩展名是否有效
            [~, ~, ext] = fileparts(imagePath);
            validExtensions = {'.png', '.jpg', '.jpeg', '.bmp', '.tiff'};
            if ~ismember(lower(ext), validExtensions)
                uialert(app.UIFigure, '文件格式不支持，请输入有效的图片路径！', '错误');
                return;
            end
        
            % 初始化处理结果
            app.imageFiles = {imagePath}; % 存储图像路径
            app.currentImageIndex = 1;
            app.processedImages = {};
            app.processedTitles = {};
        
            % 读取图像
            img = imread(imagePath);
        
            % 调用目标提取函数，设置阈值（可调整）
            threshold = 0.5; % 示例阈值，可根据需求调整
            extractedTarget = app.extractTargetByColorAndThreshold(img, threshold);
        
            % 存储处理后的图像及标题
            app.processedImages{end + 1} = extractedTarget;
            app.processedTitles{end + 1} = '提取的目标';
        
            % 显示提取后的图像
            app.displayImage();
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            % 从 EditField 获取图像路径
            imagePath = app.EditField.Value;
        
            % 检查路径是否有效
            if ~isfile(imagePath)
                uialert(app.UIFigure, '路径无效，请输入正确的图片路径！', '错误');
                return;
            end
        
            % 检查文件扩展名是否有效
            [~, ~, ext] = fileparts(imagePath);
            validExtensions = {'.png', '.jpg', '.jpeg', '.bmp', '.tiff'};
            if ~ismember(lower(ext), validExtensions)
                uialert(app.UIFigure, '文件格式不支持，请输入有效的图片路径！', '错误');
                return;
            end
        
            % 读取原始图像
            img = imread(imagePath);
        
            % 调用目标提取函数，设置阈值
            threshold = 0.5; % 示例阈值
            extractedTarget = app.extractTargetByColorAndThreshold(img, threshold);
        
            % 使用内置函数提取 LBP 特征
            lbpOriginal = app.extractLBPFeaturesBuiltin(img);
            lbpExtracted = app.extractLBPFeaturesBuiltin(extractedTarget);
        
            % 使用内置函数提取 HOG 特征
            [hogOriginal, ~] = app.extractHOGFeaturesBuiltin(img);
            [hogExtracted, ~] = app.extractHOGFeaturesBuiltin(extractedTarget);
        
            % 显示 LBP 特征直方图
            figure;
            subplot(2, 2, 1), bar(lbpOriginal), title('原图 LBP 特征');
            subplot(2, 2, 2), bar(lbpExtracted), title('提取目标 LBP 特征');
        
            % 显示 HOG 特征向量
            subplot(2, 2, 3), plot(hogOriginal), title('原图 HOG 特征');
            subplot(2, 2, 4), plot(hogExtracted), title('提取目标 HOG 特征');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '图片缩放';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '图片旋转';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '展示区域')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '输入转化的图片路径';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '灰度直方图';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '图片灰度化增强';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '图片加噪';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '空域滤波处理';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '频域滤波处理';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '图像边缘提取';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '图像目标提取';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '特征提取';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '上一张';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '下一张';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '转化参数1';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '转化参数2';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end